classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DesignUIFigure                  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Panel2_4                        matlab.ui.container.Panel
        CanadianRegionDropDownLabel     matlab.ui.control.Label
        CanadianRegionDropDown          matlab.ui.control.DropDown
        AveragePowerDemandMWEditFieldLabel  matlab.ui.control.Label
        AveragePowerDemandMWEditField   matlab.ui.control.EditField
        OptimizeButton                  matlab.ui.control.Button
        MicrogridDistributedResourceOptimizationToolLabel  matlab.ui.control.Label
        Panel2_5                        matlab.ui.container.Panel
        TotalWindTurbineCapacityMWEditFieldLabel  matlab.ui.control.Label
        TotalWindTurbineCapacityMWEditField  matlab.ui.control.NumericEditField
        TotalDieselGeneratorCapacityMWEditFieldLabel  matlab.ui.control.Label
        TotalDieselGeneratorCapacityMWEditField  matlab.ui.control.EditField
        TotalPVCapacityMWEditFieldLabel  matlab.ui.control.Label
        TotalPVCapacityMWEditField      matlab.ui.control.NumericEditField
        InitialCostMillionCADEditFieldLabel  matlab.ui.control.Label
        InitialCostMillionCADEditField  matlab.ui.control.NumericEditField
        Panel2_6                        matlab.ui.container.Panel
        MonthofDisconnectionDropDownLabel  matlab.ui.control.Label
        MonthofDisconnectionDropDown    matlab.ui.control.DropDown
        SimulateButton                  matlab.ui.control.Button
        TimeUntilReconnectiondaysEditFieldLabel  matlab.ui.control.Label
        TimeUntilReconnectiondaysEditField  matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        STOPButton                      matlab.ui.control.Button
        SimulatingPleaseWaitLabel       matlab.ui.control.Label
        SimulationCompleteLabel         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        ButtonGroup                     matlab.ui.container.ButtonGroup
        RenewableEnergyPotentialButton  matlab.ui.control.RadioButton
        CostButton                      matlab.ui.control.RadioButton
        OptimizedPowerProductionButton  matlab.ui.control.RadioButton
        SimulatedVoltageButton          matlab.ui.control.RadioButton
        SimulatedFrequencyButton        matlab.ui.control.RadioButton
        SimulatedPowerButton            matlab.ui.control.RadioButton
        Button                          matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shiped with the product.
    
    properties (Access = public)
        % Declare properties of the Design class.
        
        %Running name of simulink model (MAKE SURE TO INCLUDE "/")
        model = 'CurrentSystem2019a/';
        modelNoSlash = 'CurrentSystem2019a';
               
        %Regional solar potential (kWh/kW_installed)
            SK_solarkWhperkW = [81 111 129 135 128 128 131 129 114 102 75 67];
            AB_solarkWhperkW = [74 101 121 129 120 124 128 123 115 104 75 62];
            MB_solarkWhperkW = [79 106 132 136 127 124 127 122 103 85 66 64];
            QC_solarkWhperkW = [76 106 124 118 117 115 117 114 98 80 59 60];
            ON_solarkWhperkW = [66 92 109 115 119 124 125 118 104 86 56 52];
            NB_solarkWhperkW = [78 96 113 105 109 111 112 113 103 83 60 60];
            PE_solarkWhperkW = [69 94 110 102 109 114 114 112 100 77 54 50];
            NS_solarkWhperkW = [66 89 105 98 106 112 111 111 102 82 56 52];
            BC_solarkWhperkW = [40 58 84 99 104 110 120 118 113 74 44 37];
            NL_solarkWhperkW = [51 75 95 96 98 100 103 99 85 63 44 38];
            YT_solarkWhperkW = [29 68 108 126 118 119 110 107 82 52 30 17];
            NT_solarkWhperkW = [66 89 105 98 106 112 111 111 102 82 56 52];
            NU_solarkWhperkW = [31 89 153 175 153 126 111 92 64 51 34 15];
            
            %Regional wind potential(kWh/kW_installed)
            SK_windkWhperkW = [144 96 192 204 163 127 101 89 104 161 127 108];
            AB_windkWhperkW = [1 1 1 24 24 46 3 1 1 11 1 1];
            MB_windkWhperkW = [127 110 139 166 127 104 72 65 110 180 144 127];
            QC_windkWhperkW = [98 103 91 96 60 50 14 10 12 58 70 66];
            ON_windkWhperkW = [144 161 127 110 89 91 67 64 60 96 106 110];
            NB_windkWhperkW = [50 50 91 65 60 24 10 1 10 24 58 24];
            PE_windkWhperkW = [144 161 163 120 101 101 65 65 70 98 163 120];
            NS_windkWhperkW = [91 72 96 66 20 12 1 1 13 46 72 70];
            BC_windkWhperkW = [5 14 10 12 1 10 1 1 1 1 1 2];
            NL_windkWhperkW = [420 552 420 408 264 264 216 168 228 264 336 516];
            YT_windkWhperkW = [72 19 60 67 72 19 10 48 24 54 84 96];
            NT_windkWhperkW = [36 24 53 67 96 78 64 53 84 60 60 24];
            NU_windkWhperkW = [96 101 168 204 108 67 60 53 101 104 122 106];
    
            SolarCapCost = 2691.35;
            SolarOMCost = 21.26;
            WindCapCost = 3117.97;
            WindOMCost = 43.86;
            DieselCapCost = 3e6;
            SKgridCost = 0.182;
            ABgridCost = 0.167;
            MBgridCost = 0.096;
            QCgridCost = 0.073;
            ONgridCost = 0.125;
            NBgridCost = 0.127;
            PEgridCost = 0.168;
            NSgridCost = 0.15;
            BCgridCost = 0.124;
            NLgridCost = 0.138;
            YTgridCost = 0.145;
            NTgridCost = 0.387;
            NUgridCost = 0.375;
            
   
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CanadianRegionDropDown
        function CanadianRegionDropDownValueChanged(app, event)
        %Initialize all Capacities to zero
        assignin('base','SKSOLARCap',0);
        assignin('base','SKWindCap',0);
        assignin('base','ABSOLARCap',0);
        assignin('base','ABWindCap',0);
        assignin('base','MBSOLARCap',0);
        assignin('base','MBWindCap',0);
        assignin('base','QCSOLARCap',0);
        assignin('base','QCWindCap',0);
        assignin('base','ONSOLARCap',0);
        assignin('base','ONWindCap',0);
        assignin('base','NBSOLARCap',0);
        assignin('base','NBWindCap',0);
        assignin('base','PEISOLARCap',0);
        assignin('base','PEIWindCap',0);
        assignin('base','NSSOLARCap',0);
        assignin('base','NSWindCap',0);
        assignin('base','BCSOLARCap',0);
        assignin('base','BCWindCap',0);
        assignin('base','NLSOLARCap',0);
        assignin('base','NLWindCap',0);
        assignin('base','YTSOLARCap',0);
        assignin('base','YTWindCap',0);
        assignin('base','NTSOLARCap',0);
        assignin('base','NTWindCap',0);
        assignin('base','NUSOLARCap',0);
        assignin('base','NUWindCap',0);
        
        app.RenewableEnergyPotentialButton.Value = 1;
        app.OptimizedPowerProductionButton.Visible = 'off';
        app.CostButton.Visible = 'off';
        app.TotalWindTurbineCapacityMWEditField.Value = 0;
        app.TotalPVCapacityMWEditField.Value = 0;
        app.InitialCostMillionCADEditField.Value = 0;
        app.SimulatingPleaseWaitLabel.Visible = 'off';
        app.SimulationCompleteLabel.Visible = 'off';
        app.TimeUntilReconnectiondaysEditField.Value = 1;
        app.STOPButton.Visible = 'off';
        app.SimulateButton.Visible = 'off';
        app.SimulatedFrequencyButton.Visible = 'off';
        app.SimulatedVoltageButton.Visible = 'off';
        app.SimulatedPowerButton.Visible = 'off';
        assignin('base','GenScope',0);


            SKRenew =  [app.SK_solarkWhperkW(1), app.SK_windkWhperkW(1)
                       app.SK_solarkWhperkW(2), app.SK_windkWhperkW(2)
                       app.SK_solarkWhperkW(3), app.SK_windkWhperkW(3)
                       app.SK_solarkWhperkW(4), app.SK_windkWhperkW(4)
                       app.SK_solarkWhperkW(5), app.SK_windkWhperkW(5)
                       app.SK_solarkWhperkW(6), app.SK_windkWhperkW(6)
                       app.SK_solarkWhperkW(7), app.SK_windkWhperkW(7)
                       app.SK_solarkWhperkW(8), app.SK_windkWhperkW(8)
                       app.SK_solarkWhperkW(9), app.SK_windkWhperkW(9)
                       app.SK_solarkWhperkW(10), app.SK_windkWhperkW(10)
                       app.SK_solarkWhperkW(11), app.SK_windkWhperkW(11)
                       app.SK_solarkWhperkW(12), app.SK_windkWhperkW(12)];
            assignin('base','SKRenew',SKRenew);
            
            ABRenew =  [app.AB_solarkWhperkW(1), app.AB_windkWhperkW(1)
                       app.AB_solarkWhperkW(2), app.AB_windkWhperkW(2)
                       app.AB_solarkWhperkW(3), app.AB_windkWhperkW(3)
                       app.AB_solarkWhperkW(4), app.AB_windkWhperkW(4)
                       app.AB_solarkWhperkW(5), app.AB_windkWhperkW(5)
                       app.AB_solarkWhperkW(6), app.AB_windkWhperkW(6)
                       app.AB_solarkWhperkW(7), app.AB_windkWhperkW(7)
                       app.AB_solarkWhperkW(8), app.AB_windkWhperkW(8)
                       app.AB_solarkWhperkW(9), app.AB_windkWhperkW(9)
                       app.AB_solarkWhperkW(10), app.AB_windkWhperkW(10)
                       app.AB_solarkWhperkW(11), app.AB_windkWhperkW(11)
                       app.AB_solarkWhperkW(12), app.AB_windkWhperkW(12)];
            assignin('base','ABRenew',ABRenew);
                  
            MBRenew = [app.MB_solarkWhperkW(1), app.MB_windkWhperkW(1)
                       app.MB_solarkWhperkW(2), app.MB_windkWhperkW(2)
                       app.MB_solarkWhperkW(3), app.MB_windkWhperkW(3)
                       app.MB_solarkWhperkW(4), app.MB_windkWhperkW(4)
                       app.MB_solarkWhperkW(5), app.MB_windkWhperkW(5)
                       app.MB_solarkWhperkW(6), app.MB_windkWhperkW(6)
                       app.MB_solarkWhperkW(7), app.MB_windkWhperkW(7)
                       app.MB_solarkWhperkW(8), app.MB_windkWhperkW(8)
                       app.MB_solarkWhperkW(9), app.MB_windkWhperkW(9)
                       app.MB_solarkWhperkW(10), app.MB_windkWhperkW(10)
                       app.MB_solarkWhperkW(11), app.MB_windkWhperkW(11)
                       app.MB_solarkWhperkW(12), app.MB_windkWhperkW(12)];
            assignin('base','MBRenew',MBRenew);  
            
             QCRenew = [app.QC_solarkWhperkW(1), app.QC_windkWhperkW(1)
                       app.QC_solarkWhperkW(2), app.QC_windkWhperkW(2)
                       app.QC_solarkWhperkW(3), app.QC_windkWhperkW(3)
                       app.QC_solarkWhperkW(4), app.QC_windkWhperkW(4)
                       app.QC_solarkWhperkW(5), app.QC_windkWhperkW(5)
                       app.QC_solarkWhperkW(6), app.QC_windkWhperkW(6)
                       app.QC_solarkWhperkW(7), app.QC_windkWhperkW(7)
                       app.QC_solarkWhperkW(8), app.QC_windkWhperkW(8)
                       app.QC_solarkWhperkW(9), app.QC_windkWhperkW(9)
                       app.QC_solarkWhperkW(10), app.QC_windkWhperkW(10)
                       app.QC_solarkWhperkW(11), app.QC_windkWhperkW(11)
                       app.QC_solarkWhperkW(12), app.QC_windkWhperkW(12)];
             assignin('base','QCRenew',QCRenew);
                   
              ONRenew = [app.ON_solarkWhperkW(1), app.ON_windkWhperkW(1)
                       app.ON_solarkWhperkW(2), app.ON_windkWhperkW(2)
                       app.ON_solarkWhperkW(3), app.ON_windkWhperkW(3)
                       app.ON_solarkWhperkW(4), app.ON_windkWhperkW(4)
                       app.ON_solarkWhperkW(5), app.ON_windkWhperkW(5)
                       app.ON_solarkWhperkW(6), app.ON_windkWhperkW(6)
                       app.ON_solarkWhperkW(7), app.ON_windkWhperkW(7)
                       app.ON_solarkWhperkW(8), app.ON_windkWhperkW(8)
                       app.ON_solarkWhperkW(9), app.ON_windkWhperkW(9)
                       app.ON_solarkWhperkW(10), app.ON_windkWhperkW(10)
                       app.ON_solarkWhperkW(11), app.ON_windkWhperkW(11)
                       app.ON_solarkWhperkW(12), app.ON_windkWhperkW(12)];
                assignin('base','ONRenew',ONRenew);
               
              NBRenew = [app.NB_solarkWhperkW(1), app.NB_windkWhperkW(1)
                       app.NB_solarkWhperkW(2), app.NB_windkWhperkW(2)
                       app.NB_solarkWhperkW(3), app.NB_windkWhperkW(3)
                       app.NB_solarkWhperkW(4), app.NB_windkWhperkW(4)
                       app.NB_solarkWhperkW(5), app.NB_windkWhperkW(5)
                       app.NB_solarkWhperkW(6), app.NB_windkWhperkW(6)
                       app.NB_solarkWhperkW(7), app.NB_windkWhperkW(7)
                       app.NB_solarkWhperkW(8), app.NB_windkWhperkW(8)
                       app.NB_solarkWhperkW(9), app.NB_windkWhperkW(9)
                       app.NB_solarkWhperkW(10), app.NB_windkWhperkW(10)
                       app.NB_solarkWhperkW(11), app.NB_windkWhperkW(11)
                       app.NB_solarkWhperkW(12), app.NB_windkWhperkW(12)];
               assignin('base','NBRenew',NBRenew);
                   
              PERenew = [app.PE_solarkWhperkW(1), app.PE_windkWhperkW(1)
                       app.PE_solarkWhperkW(2), app.PE_windkWhperkW(2)
                       app.PE_solarkWhperkW(3), app.PE_windkWhperkW(3)
                       app.PE_solarkWhperkW(4), app.PE_windkWhperkW(4)
                       app.PE_solarkWhperkW(5), app.PE_windkWhperkW(5)
                       app.PE_solarkWhperkW(6), app.PE_windkWhperkW(6)
                       app.PE_solarkWhperkW(7), app.PE_windkWhperkW(7)
                       app.PE_solarkWhperkW(8), app.PE_windkWhperkW(8)
                       app.PE_solarkWhperkW(9), app.PE_windkWhperkW(9)
                       app.PE_solarkWhperkW(10), app.PE_windkWhperkW(10)
                       app.PE_solarkWhperkW(11), app.PE_windkWhperkW(11)
                       app.PE_solarkWhperkW(12), app.PE_windkWhperkW(12)];
               assignin('base','PERenew',PERenew);
                 
              NSRenew = [app.NS_solarkWhperkW(1), app.NS_windkWhperkW(1)
                       app.NS_solarkWhperkW(2), app.NS_windkWhperkW(2)
                       app.NS_solarkWhperkW(3), app.NS_windkWhperkW(3)
                       app.NS_solarkWhperkW(4), app.NS_windkWhperkW(4)
                       app.NS_solarkWhperkW(5), app.NS_windkWhperkW(5)
                       app.NS_solarkWhperkW(6), app.NS_windkWhperkW(6)
                       app.NS_solarkWhperkW(7), app.NS_windkWhperkW(7)
                       app.NS_solarkWhperkW(8), app.NS_windkWhperkW(8)
                       app.NS_solarkWhperkW(9), app.NS_windkWhperkW(9)
                       app.NS_solarkWhperkW(10), app.NS_windkWhperkW(10)
                       app.NS_solarkWhperkW(11), app.NS_windkWhperkW(11)
                       app.NS_solarkWhperkW(12), app.NS_windkWhperkW(12)];
              assignin('base','NSRenew',NSRenew);
                   
              BCRenew = [app.BC_solarkWhperkW(1), app.BC_windkWhperkW(1)
                       app.BC_solarkWhperkW(2), app.BC_windkWhperkW(2)
                       app.BC_solarkWhperkW(3), app.BC_windkWhperkW(3)
                       app.BC_solarkWhperkW(4), app.BC_windkWhperkW(4)
                       app.BC_solarkWhperkW(5), app.BC_windkWhperkW(5)
                       app.BC_solarkWhperkW(6), app.BC_windkWhperkW(6)
                       app.BC_solarkWhperkW(7), app.BC_windkWhperkW(7)
                       app.BC_solarkWhperkW(8), app.BC_windkWhperkW(8)
                       app.BC_solarkWhperkW(9), app.BC_windkWhperkW(9)
                       app.BC_solarkWhperkW(10), app.BC_windkWhperkW(10)
                       app.BC_solarkWhperkW(11), app.BC_windkWhperkW(11)
                       app.BC_solarkWhperkW(12), app.BC_windkWhperkW(12)];
               assignin('base','BCRenew',BCRenew);
               
              NLRenew = [app.NL_solarkWhperkW(1), app.NL_windkWhperkW(1)
                       app.NL_solarkWhperkW(2), app.NL_windkWhperkW(2)
                       app.NL_solarkWhperkW(3), app.NL_windkWhperkW(3)
                       app.NL_solarkWhperkW(4), app.NL_windkWhperkW(4)
                       app.NL_solarkWhperkW(5), app.NL_windkWhperkW(5)
                       app.NL_solarkWhperkW(6), app.NL_windkWhperkW(6)
                       app.NL_solarkWhperkW(7), app.NL_windkWhperkW(7)
                       app.NL_solarkWhperkW(8), app.NL_windkWhperkW(8)
                       app.NL_solarkWhperkW(9), app.NL_windkWhperkW(9)
                       app.NL_solarkWhperkW(10), app.NL_windkWhperkW(10)
                       app.NL_solarkWhperkW(11), app.NL_windkWhperkW(11)
                       app.NL_solarkWhperkW(12), app.NL_windkWhperkW(12)];
              assignin('base','NLRenew',NLRenew);
            
              YTRenew = [app.YT_solarkWhperkW(1), app.YT_windkWhperkW(1)
                       app.YT_solarkWhperkW(2), app.YT_windkWhperkW(2)
                       app.YT_solarkWhperkW(3), app.YT_windkWhperkW(3)
                       app.YT_solarkWhperkW(4), app.YT_windkWhperkW(4)
                       app.YT_solarkWhperkW(5), app.YT_windkWhperkW(5)
                       app.YT_solarkWhperkW(6), app.YT_windkWhperkW(6)
                       app.YT_solarkWhperkW(7), app.YT_windkWhperkW(7)
                       app.YT_solarkWhperkW(8), app.YT_windkWhperkW(8)
                       app.YT_solarkWhperkW(9), app.YT_windkWhperkW(9)
                       app.YT_solarkWhperkW(10), app.YT_windkWhperkW(10)
                       app.YT_solarkWhperkW(11), app.YT_windkWhperkW(11)
                       app.YT_solarkWhperkW(12), app.YT_windkWhperkW(12)];
               assignin('base','YTRenew',YTRenew);
                   
               NTRenew = [app.NT_solarkWhperkW(1), app.NT_windkWhperkW(1)
                       app.NT_solarkWhperkW(2), app.NT_windkWhperkW(2)
                       app.NT_solarkWhperkW(3), app.NT_windkWhperkW(3)
                       app.NT_solarkWhperkW(4), app.NT_windkWhperkW(4)
                       app.NT_solarkWhperkW(5), app.NT_windkWhperkW(5)
                       app.NT_solarkWhperkW(6), app.NT_windkWhperkW(6)
                       app.NT_solarkWhperkW(7), app.NT_windkWhperkW(7)
                       app.NT_solarkWhperkW(8), app.NT_windkWhperkW(8)
                       app.NT_solarkWhperkW(9), app.NT_windkWhperkW(9)
                       app.NT_solarkWhperkW(10), app.NT_windkWhperkW(10)
                       app.NT_solarkWhperkW(11), app.NT_windkWhperkW(11)
                       app.NT_solarkWhperkW(12), app.NT_windkWhperkW(12)];
                assignin('base','NTRenew',NTRenew);
                   
               NURenew = [app.NU_solarkWhperkW(1), app.NU_windkWhperkW(1)
                       app.NU_solarkWhperkW(2), app.NU_windkWhperkW(2)
                       app.NU_solarkWhperkW(3), app.NU_windkWhperkW(3)
                       app.NU_solarkWhperkW(4), app.NU_windkWhperkW(4)
                       app.NU_solarkWhperkW(5), app.NU_windkWhperkW(5)
                       app.NU_solarkWhperkW(6), app.NU_windkWhperkW(6)
                       app.NU_solarkWhperkW(7), app.NU_windkWhperkW(7)
                       app.NU_solarkWhperkW(8), app.NU_windkWhperkW(8)
                       app.NU_solarkWhperkW(9), app.NU_windkWhperkW(9)
                       app.NU_solarkWhperkW(10), app.NU_windkWhperkW(10)
                       app.NU_solarkWhperkW(11), app.NU_windkWhperkW(11)
                       app.NU_solarkWhperkW(12), app.NU_windkWhperkW(12)];
                assignin('base','NURenew',NURenew);
            
          
          switch(app.CanadianRegionDropDown.Value)
                
                case 'SK'   
                  app.UIAxes.cla;
                  bar(app.UIAxes, SKRenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Saskatchewan Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'AB'
                  app.UIAxes.cla;
                  bar(app.UIAxes, ABRenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Alberta Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'MB'
                  app.UIAxes.cla;
                  bar(app.UIAxes, MBRenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Manitoba Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'QC'
                  app.UIAxes.cla;
                  bar(app.UIAxes, QCRenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Quebec Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'ON'
                  app.UIAxes.cla;
                  bar(app.UIAxes, ONRenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Ontario Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'NB'
                  app.UIAxes.cla;
                  bar(app.UIAxes, NBRenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'New Brunswick Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'PE'
                  app.UIAxes.cla;
                  bar(app.UIAxes, PERenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Prince Edward Island Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'NS'
                  app.UIAxes.cla;
                  bar(app.UIAxes, NSRenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Nova Scotia Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'BC'
                  app.UIAxes.cla;
                  bar(app.UIAxes, BCRenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'British Columbia Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'NL'
                  app.UIAxes.cla;
                  bar(app.UIAxes, NLRenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Newfoundland & Labrador Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 700]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'YT'
                  app.UIAxes.cla;
                  bar(app.UIAxes, YTRenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Yukon Territory Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'NT'
                  app.UIAxes.cla;
                  bar(app.UIAxes, NTRenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Northwest Territory Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                case 'NU'
                  app.UIAxes.cla;
                  bar(app.UIAxes, NURenew, 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Nunavut Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
            end 
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
        if bdIsLoaded(app.modelNoSlash) == 0
        open_system(app.modelNoSlash);
        end
        
        %Defining Connection Lines
        strWind = append(app.model,'Wind Turbine');
        strSolar = append(app.model,'Solar Array');
        strSKWind = append(app.model,'SK WIND PROFILE');
        strSKSolar = append(app.model,'SK SOLAR PROFILE');
        strABWind = append(app.model,'AB WIND PROFILE');
        strABSolar = append(app.model,'AB SOLAR PROFILE');
        strMBWind = append(app.model,'MB WIND PROFILE');
        strMBSolar = append(app.model,'MB SOLAR PROFILE');
        strQCWind = append(app.model,'QC WIND PROFILE');
        strQCSolar = append(app.model,'QC SOLAR PROFILE');
        strONWind = append(app.model,'ON WIND PROFILE');
        strONSolar = append(app.model,'ON SOLAR PROFILE');
        strNBWind = append(app.model,'NB WIND PROFILE');
        strNBSolar = append(app.model,'NB SOLAR PROFILE');
        strPEWind = append(app.model,'PEI WIND PROFILE');
        strPESolar = append(app.model,'PEI SOLAR PROFILE');
        strNSWind = append(app.model,'NS WIND PROFILE');
        strNSSolar = append(app.model,'NS SOLAR PROFILE');
        strBCWind = append(app.model,'BC WIND PROFILE');
        strBCSolar = append(app.model,'BC SOLAR PROFILE');
        strNLWind = append(app.model,'NL WIND PROFILE');
        strNLSolar = append(app.model,'NL SOLAR PROFILE');
        strYTWind = append(app.model,'YT WIND PROFILE');
        strYTSolar = append(app.model,'YT SOLAR PROFILE');
        strNTWind = append(app.model,'NT WIND PROFILE');
        strNTSolar = append(app.model,'NT SOLAR PROFILE');
        strNUWind = append(app.model,'NU WIND PROFILE');
        strNUSolar = append(app.model,'NU SOLAR PROFILE');
        
        Wind = get_param(strWind, 'PortHandles');
        Solar = get_param(strSolar, 'PortHandles')
        SKWind = get_param(strSKWind,'PortHandles');
        SKSolar = get_param(strSKSolar, 'PortHandles');
        ABWind = get_param(strABWind,'PortHandles');
        ABSolar = get_param(strABSolar, 'PortHandles');
        MBWind = get_param(strMBWind,'PortHandles');
        MBSolar = get_param(strMBSolar, 'PortHandles');
        QCWind = get_param(strQCWind,'PortHandles');
        QCSolar = get_param(strQCSolar, 'PortHandles');
        ONWind = get_param(strONWind,'PortHandles');
        ONSolar = get_param(strONSolar, 'PortHandles');
        NBWind = get_param(strNBWind,'PortHandles');
        NBSolar = get_param(strNBSolar, 'PortHandles');
        PEWind = get_param(strPEWind,'PortHandles');
        PESolar = get_param(strPESolar, 'PortHandles');
        NSWind = get_param(strNSWind,'PortHandles');
        NSSolar = get_param(strNSSolar, 'PortHandles');
        BCWind = get_param(strBCWind,'PortHandles');
        BCSolar = get_param(strBCSolar, 'PortHandles');
        NLWind = get_param(strNLWind,'PortHandles');
        NLSolar = get_param(strNLSolar, 'PortHandles');
        YTWind = get_param(strYTWind,'PortHandles');
        YTSolar = get_param(strYTSolar, 'PortHandles');
        NTWind = get_param(strNTWind,'PortHandles');
        NTSolar = get_param(strNTSolar, 'PortHandles');
        NUWind = get_param(strNUWind,'PortHandles');
        NUSolar = get_param(strNUSolar, 'PortHandles');
        
              
        %Initializing model by deleting all power supply connections
        %Use 'try' function, as an error appears if you try to delete line
        %which doesnt exist.
        %Seperate try function for each test is necessary. It returns upon
        %failure
        try
        delete_line(app.model,SKWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,SKSolar.Outport(2),Solar.Inport(1));
        end
        try              
        delete_line(app.model,ABWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,ABSolar.Outport(2),Solar.Inport(1));
        end
        try
        delete_line(app.model,MBWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,MBSolar.Outport(2),Solar.Inport(1));
        end
        try
        delete_line(app.model,QCWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,QCSolar.Outport(2),Solar.Inport(1));
        end
        try
        delete_line(app.model,ONWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,ONSolar.Outport(2),Solar.Inport(1));
        end
        try
        delete_line(app.model,NBWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,NBSolar.Outport(2),Solar.Inport(1));
        end
        try     
        delete_line(app.model,PEWind.Outport(2),Wind.Inport(1));
        end
        try       
        delete_line(app.model,PESolar.Outport(2),Solar.Inport(1));
        end
        try        
        delete_line(app.model,NSWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,NSSolar.Outport(2),Solar.Inport(1));
        end
        try
        delete_line(app.model,BCWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,BCSolar.Outport(2),Solar.Inport(1));
        end
        try
        delete_line(app.model,NLWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,NLSolar.Outport(2),Solar.Inport(1));
        end
        try
        delete_line(app.model,YTWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,YTSolar.Outport(2),Solar.Inport(1));
        end
        try
        delete_line(app.model,NTWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,NTSolar.Outport(2),Solar.Inport(1));
        end
        try
        delete_line(app.model,NUWind.Outport(2),Wind.Inport(1));
        end
        try
        delete_line(app.model,NUSolar.Outport(2),Solar.Inport(1));
        end
        
        switch(app.CanadianRegionDropDown.Value)
               
            case 'SK'
            add_line(app.model,SKWind.Outport(2),Wind.Inport(1));
            add_line(app.model,SKSolar.Outport(2),Solar.Inport(1));
            
            case 'AB'
            add_line(app.model,ABWind.Outport(2),Wind.Inport(1));
            add_line(app.model,ABSolar.Outport(2),Solar.Inport(1)); 
            
            case 'MB'
            add_line(app.model,MBWind.Outport(2),Wind.Inport(1));
            add_line(app.model,MBSolar.Outport(2),Solar.Inport(1));
            
            case 'QC'
            add_line(app.model,QCWind.Outport(2),Wind.Inport(1));
            add_line(app.model,QCSolar.Outport(2),Solar.Inport(1));
            
            case 'ON'
            add_line(app.model,ONWind.Outport(2),Wind.Inport(1));
            add_line(app.model,ONSolar.Outport(2),Solar.Inport(1));
            
            case 'NB'
            add_line(app.model,NBWind.Outport(2),Wind.Inport(1));
            add_line(app.model,NBSolar.Outport(2),Solar.Inport(1));
            
            case 'PE'
            add_line(app.model,PEWind.Outport(2),Wind.Inport(1));
            add_line(app.model,PESolar.Outport(2),Solar.Inport(1));
            
            case 'NS'
            add_line(app.model,NSWind.Outport(2),Wind.Inport(1));
            add_line(app.model,NSSolar.Outport(2),Solar.Inport(1));
            
            case 'BC'
            add_line(app.model,BCWind.Outport(2),Wind.Inport(1));
            add_line(app.model,BCSolar.Outport(2),Solar.Inport(1));
            
            case 'NL'
            add_line(app.model,NLWind.Outport(2),Wind.Inport(1));
            add_line(app.model,NLSolar.Outport(2),Solar.Inport(1));
            
            case 'YT'
            add_line(app.model,YTWind.Outport(2),Wind.Inport(1));
            add_line(app.model,YTSolar.Outport(2),Solar.Inport(1));
            
            case 'NT'
            add_line(app.model,NTWind.Outport(2),Wind.Inport(1));
            add_line(app.model,NTSolar.Outport(2),Solar.Inport(1));
            
            case 'NU'
            add_line(app.model,NUWind.Outport(2),Wind.Inport(1));
            add_line(app.model,NUSolar.Outport(2),Solar.Inport(1));
        end
        
        assignin('base', 'Yearsec', 24*30*60*60);
        switch(app.MonthofDisconnectionDropDown.Value)
            
            case 'Jan'
            assignin('base', 'T_Disconnect', 0.3*evalin('base','Yearsec'));
            
            case 'Feb'
            assignin('base', 'T_Disconnect', 1*evalin('base','Yearsec'));
            
            case 'Mar'
            assignin('base', 'T_Disconnect', 2*evalin('base','Yearsec'));
            
            case 'Apr'
            assignin('base', 'T_Disconnect', 3*evalin('base','Yearsec'));
            
            case 'May'
            assignin('base', 'T_Disconnect', 4*evalin('base','Yearsec'));
            
            case 'Jun'
            assignin('base', 'T_Disconnect', 5*evalin('base','Yearsec'));
            
            case 'Jul'
            assignin('base', 'T_Disconnect', 6*evalin('base','Yearsec'));
            
            case 'Aug'
            assignin('base', 'T_Disconnect', 7*evalin('base','Yearsec'));
            
            case 'Sep'
            assignin('base', 'T_Disconnect', 8*evalin('base','Yearsec'));
            
            case 'Oct'
            assignin('base', 'T_Disconnect', 9*evalin('base','Yearsec'));
            
            case 'Nov'
            assignin('base', 'T_Disconnect', 10*evalin('base','Yearsec'));
            
            case 'Dec'
            assignin('base', 'T_Disconnect', 11*evalin('base','Yearsec'));
        end
        
        value = evalin('base','T_Disconnect') + app.TimeUntilReconnectiondaysEditField.Value*86400;
        assignin('base','T_Reconnect',value);
        
        %Simulating the system with selected params
        app.SimulatingPleaseWaitLabel.Visible = 'on';
        app.STOPButton.Visible = 'on';
        sim(app.modelNoSlash, 24*365*60*60);
        app.SimulatingPleaseWaitLabel.Visible = 'off';
        app.SimulationCompleteLabel.Visible = 'on';       
        app.SimulatedVoltageButton.Visible = 'on';
        app.SimulatedFrequencyButton.Visible = 'on';
        app.SimulatedPowerButton.Visible = 'on';
        
        assignin('base','GenScope',GenScope);        
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DesignUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {688, 688};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {303, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function OptimizeDistributedResourcesButtonPushed(app, event)
            
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
           
        switch (app.CanadianRegionDropDown.Value)
                
                case 'SK' %WIND HIGH
                SKSOLARTotal= 0;
                SKWindTotal = 0;
                    for i = 1:1:12
                        SKSOLARTotal = SKSOLARTotal + app.SK_solarkWhperkW(i);
                        SKWindTotal= SKWindTotal + app.SK_windkWhperkW(i);
                    end
                   
                SKRatio = SKSOLARTotal/SKWindTotal;
                SKWindGen = 2/(1+SKRatio);  
                SKWindCap = round(SKWindGen*8760/SKWindTotal,1);
                SKSOLARCap = round(SKWindCap*SKRatio,1);
                app.TotalWindTurbineCapacityMWEditField.Value = SKWindCap;
                app.TotalPVCapacityMWEditField.Value = SKSOLARCap;
                assignin('base', 'SKWindCap', SKWindCap*1e6);
                assignin('base', 'SKSOLARCap', SKSOLARCap*1e6); 
                SKInitialCost = round((app.SolarCapCost*SKSOLARCap*1000 + app.WindCapCost*SKWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = SKInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                    
                case 'AB' %SOLAR HIGH
                ABSOLARTotal= 0;
                ABWindTotal = 0;
                for i = 1:1:12
                ABSOLARTotal = ABSOLARTotal + app.AB_solarkWhperkW(i);
                ABWindTotal= ABWindTotal + app.AB_windkWhperkW(i);
                
                end
                ABRatio = ABWindTotal/ABSOLARTotal;
                ABSOLARGen = 2/(1+ABRatio);  
                ABSOLARCap = round(ABSOLARGen*8760/ABSOLARTotal,1)+1;
                ABWindCap = 0;
                app.TotalWindTurbineCapacityMWEditField.Value = ABWindCap;
                app.TotalPVCapacityMWEditField.Value = ABSOLARCap;
                assignin('base', 'ABWindCap', ABWindCap*1e6);
                assignin('base', 'ABSOLARCap', ABSOLARCap*1e6); 
                ABInitialCost = round((app.SolarCapCost*ABSOLARCap*1000 + app.WindCapCost*ABWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = ABInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                
                case 'MB' %WIND HIGH
                MBSOLARTotal= 0;
                MBWindTotal = 0;
                    
                for i = 1:1:12
                MBSOLARTotal = MBSOLARTotal + app.MB_solarkWhperkW(i);
                MBWindTotal= MBWindTotal + app.MB_windkWhperkW(i);
                end
                MBRatio = MBSOLARTotal/MBWindTotal;
                MBWindGen = 2/(1+MBRatio);  
                MBWindCap = round(MBWindGen*8760/MBWindTotal,1);
                MBSOLARCap = round(MBWindCap*MBRatio,1);
                app.TotalWindTurbineCapacityMWEditField.Value = MBWindCap;
                app.TotalPVCapacityMWEditField.Value = MBSOLARCap;
                assignin('base', 'MBWindCap', MBWindCap*1e6);
                assignin('base', 'MBSOLARCap', MBSOLARCap*1e6);
                MBInitialCost = round((app.SolarCapCost*MBSOLARCap*1000 + app.WindCapCost*MBWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = MBInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                
                case 'QC' %SOLAR HIGH
                QCSOLARTotal= 0;
                QCWindTotal = 0;
                for i = 1:1:12
                QCSOLARTotal = QCSOLARTotal + app.QC_solarkWhperkW(i);
                QCWindTotal= QCWindTotal + app.QC_windkWhperkW(i);
               
                end
                QCRatio = QCWindTotal/QCSOLARTotal;
                QCSOLARGen = 2/(1+QCRatio);  
                QCSOLARCap = round(QCSOLARGen*8760/QCSOLARTotal,1);
                QCWindCap = round(QCSOLARCap*QCRatio,1);
                app.TotalWindTurbineCapacityMWEditField.Value = QCWindCap;
                app.TotalPVCapacityMWEditField.Value = QCSOLARCap;
                assignin('base', 'QCWindCap', QCWindCap*1e6);
                assignin('base', 'QCSOLARCap', QCSOLARCap*1e6);
                QCInitialCost = round((app.SolarCapCost*QCSOLARCap*1000 + app.WindCapCost*QCWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = QCInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                
                case 'ON' %WIND HIGH
                ONSOLARTotal= 0;
                ONWindTotal = 0;
                for i = 1:1:12
                ONSOLARTotal = ONSOLARTotal + app.ON_solarkWhperkW(i);
                ONWindTotal= ONWindTotal + app.ON_windkWhperkW(i);
                
                end
                ONRatio = ONSOLARTotal/ONWindTotal;
                ONWindGen = 2/(1+ONRatio);  
                ONWindCap = round(ONWindGen*8760/ONWindTotal,1);
                ONSOLARCap = round(ONWindCap*ONRatio,1);
                app.TotalWindTurbineCapacityMWEditField.Value = ONWindCap;
                app.TotalPVCapacityMWEditField.Value = ONSOLARCap;
                assignin('base', 'ONWindCap', ONWindCap*1e6);
                assignin('base', 'ONSOLARCap', ONSOLARCap*1e6);
                ONInitialCost = round((app.SolarCapCost*ONSOLARCap*1000 + app.WindCapCost*ONWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = ONInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                
                case 'NB' %SOLAR HIGH
                NBSOLARTotal= 0;
                NBWindTotal = 0;
                for i = 1:1:12
                NBSOLARTotal = NBSOLARTotal + app.NB_solarkWhperkW(i);
                NBWindTotal= NBWindTotal + app.NB_windkWhperkW(i);
                
                end
                NBRatio = NBWindTotal/NBSOLARTotal;
                NBSOLARGen = 2/(1+NBRatio);  
                NBSOLARCap = round(NBSOLARGen*8760/NBSOLARTotal,1);
                NBWindCap = round(NBSOLARCap*NBRatio,1);
                app.TotalWindTurbineCapacityMWEditField.Value = NBWindCap;
                app.TotalPVCapacityMWEditField.Value = NBSOLARCap;
                assignin('base', 'NBWindCap', NBWindCap*1e6);
                assignin('base', 'NBSOLARCap', NBSOLARCap*1e6);
                NBInitialCost = round((app.SolarCapCost*NBSOLARCap*1000 + app.WindCapCost*NBWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = NBInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                
                case 'PE' %WIND HIGH
                PESOLARTotal= 0;
                PEWindTotal = 0;
                for i = 1:1:12
                PESOLARTotal = PESOLARTotal + app.PE_solarkWhperkW(i);
                PEWindTotal= PEWindTotal + app.PE_windkWhperkW(i);
               
                end
                PERatio = PESOLARTotal/PEWindTotal;
                PEWindGen = 2/(1+PERatio);  
                PEWindCap = round(PEWindGen*8760/PEWindTotal,1);
                PESOLARCap = round(PEWindCap*PERatio,1);
                app.TotalWindTurbineCapacityMWEditField.Value = PEWindCap;
                app.TotalPVCapacityMWEditField.Value = PESOLARCap;
                assignin('base', 'PEIWindCap', PEWindCap*1e6);
                assignin('base', 'PEISOLARCap', PESOLARCap*1e6);
                PEInitialCost = round((app.SolarCapCost*PESOLARCap*1000 + app.WindCapCost*PEWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = PEInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                
                case 'NS' %SOLAR HIGH
                NSSOLARTotal= 0;
                NSWindTotal = 0;
                for i = 1:1:12
                NSSOLARTotal = NSSOLARTotal + app.NS_solarkWhperkW(i);
                NSWindTotal= NSWindTotal + app.NS_windkWhperkW(i);
              
                end
                NSRatio = NSWindTotal/NSSOLARTotal;
                NSSOLARGen = 2/(1+NSRatio);  
                NSSOLARCap = round(NSSOLARGen*8760/NSSOLARTotal,1);
                NSWindCap = round(NSSOLARCap*NSRatio,1);
                app.TotalWindTurbineCapacityMWEditField.Value = NSWindCap;
                app.TotalPVCapacityMWEditField.Value = NSSOLARCap;
                assignin('base', 'NSWindCap', NSWindCap*1e6);
                assignin('base', 'NSSOLARCap', NSSOLARCap*1e6);
                NSInitialCost = round((app.SolarCapCost*NSSOLARCap*1000 + app.WindCapCost*NSWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = NSInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                
                case 'BC' %SOLAR HIGH
                BCSOLARTotal= 0;
                BCWindTotal = 0;
                for i = 1:1:12
                BCSOLARTotal = BCSOLARTotal + app.BC_solarkWhperkW(i);
                BCWindTotal= BCWindTotal + app.BC_windkWhperkW(i);
               
                end
                BCRatio = BCWindTotal/BCSOLARTotal;
                BCSOLARGen = 2/(1+BCRatio);  
                BCSOLARCap = round(BCSOLARGen*8760/BCSOLARTotal,1)+1;
                BCWindCap = 0;
                app.TotalWindTurbineCapacityMWEditField.Value = BCWindCap;
                app.TotalPVCapacityMWEditField.Value = BCSOLARCap;
                assignin('base', 'BCWindCap', BCWindCap*1e6);
                assignin('base', 'BCSOLARCap', BCSOLARCap*1e6); 
                BCInitialCost = round((app.SolarCapCost*BCSOLARCap*1000 + app.WindCapCost*BCWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = BCInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                
                case 'NL' %WIND HIGH
                NLSOLARTotal= 0;
                NLWindTotal = 0;
                for i = 1:1:12
                NLSOLARTotal = NLSOLARTotal + app.NL_solarkWhperkW(i);
                NLWindTotal= NLWindTotal + app.NL_windkWhperkW(i);
                
                end
                NLRatio = NLSOLARTotal/NLWindTotal;
                NLWindGen = 2/(1+NLRatio);  
                NLWindCap = round(NLWindGen*8760/NLWindTotal,1);
                NLSOLARCap = round(NLWindCap*NLRatio,1)+1.2;
                app.TotalWindTurbineCapacityMWEditField.Value = NLWindCap;
                app.TotalPVCapacityMWEditField.Value = NLSOLARCap;
                assignin('base', 'NLWindCap', NLWindCap*1e6);
                assignin('base', 'NLSOLARCap', NLSOLARCap*1e6);
                NLInitialCost = round((app.SolarCapCost*NLSOLARCap*1000 + app.WindCapCost*NLWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = NLInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                
                case 'YT'%SOLAR HIGH
                YTSOLARTotal= 0;
                YTWindTotal = 0;
                for i = 1:1:12
                YTSOLARTotal = YTSOLARTotal + app.YT_solarkWhperkW(i);
                YTWindTotal= YTWindTotal + app.YT_windkWhperkW(i);
                
                end
                YTRatio = YTWindTotal/YTSOLARTotal;
                YTSOLARGen = 2/(1+YTRatio);  
                YTSOLARCap = round(YTSOLARGen*8760/YTSOLARTotal,1);
                YTWindCap = round(YTSOLARCap*YTRatio,1);
                app.TotalWindTurbineCapacityMWEditField.Value = YTWindCap;
                app.TotalPVCapacityMWEditField.Value = YTSOLARCap;
                assignin('base', 'YTWindCap', YTWindCap*1e6);
                assignin('base', 'YTSOLARCap', YTSOLARCap*1e6); 
                YTInitialCost = round((app.SolarCapCost*YTSOLARCap*1000 + app.WindCapCost*YTWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = YTInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                
                case 'NT'%SOLAR HIGH
                NTSOLARTotal= 0;
                NTWindTotal = 0;
                for i = 1:1:12
                NTSOLARTotal = NTSOLARTotal + app.NT_solarkWhperkW(i);
                NTWindTotal= NTWindTotal + app.NT_windkWhperkW(i);
                
                end
                NTRatio = NTWindTotal/NTSOLARTotal;
                NTSOLARGen = 2/(1+NTRatio);  
                NTSOLARCap = round(NTSOLARGen*8760/NTSOLARTotal,1);
                NTWindCap = round(NTSOLARCap*NTRatio,1);
                app.TotalWindTurbineCapacityMWEditField.Value = NTWindCap;
                app.TotalPVCapacityMWEditField.Value = NTSOLARCap;
                assignin('base', 'NTWindCap', NTWindCap*1e6);
                assignin('base', 'NTSOLARCap', NTSOLARCap*1e6);
                NTInitialCost = round((app.SolarCapCost*NTSOLARCap*1000 + app.WindCapCost*NTWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = NTInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
                
                case 'NU'%WIND HIGH
                NUSOLARTotal= 0;
                NUWindTotal = 0;
                for i = 1:1:12
                NUSOLARTotal = NUSOLARTotal + app.NU_solarkWhperkW(i);
                NUWindTotal= NUWindTotal + app.NU_windkWhperkW(i);
                
                end
                NURatio = NUSOLARTotal/NUWindTotal;
                NUWindGen = 2/(1+NURatio);  
                NUWindCap = round(NUWindGen*8760/NUWindTotal,1);
                NUSOLARCap = round(NUWindCap*NURatio,1);
                app.TotalWindTurbineCapacityMWEditField.Value = NUWindCap;
                app.TotalPVCapacityMWEditField.Value = NUSOLARCap;
                assignin('base', 'NUWindCap', NUWindCap*1e6);
                assignin('base', 'NUSOLARCap', NUSOLARCap*1e6);
                NUInitialCost = round((app.SolarCapCost*NUSOLARCap*1000 + app.WindCapCost*NUWindCap*1000 + app.DieselCapCost)/1000000,2);
                app.InitialCostMillionCADEditField.Value = NUInitialCost;
                app.OptimizedPowerProductionButton.Visible = 'on';
		app.CostButton.Visible = 'on';
        end
        
        
        app.SimulateButton.Visible = 'on';
        
        end

        % Callback function
        function RunButtonPushed(app, event)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
        switch(app.ButtonGroup.SelectedObject.Text)
            case 'Optimized Power Production'
                              
                if(app.CanadianRegionDropDown.Value == 'SK')                    
                       SKrenewOpt =  [(1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','SKSOLARCap')*app.SK_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','SKWindCap')*app.SK_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,SKrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'Saskatchewan Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                end 
                if(app.CanadianRegionDropDown.Value == 'AB')
                       ABrenewOpt =  [(1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','ABSOLARCap')*app.AB_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','ABWindCap')*app.AB_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,ABrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'Alberta Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end   
                 if(app.CanadianRegionDropDown.Value == 'MB')
                       MBrenewOpt =  [(1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','MBSOLARCap')*app.MB_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','MBWindCap')*app.MB_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,MBrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'Manitoba Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end   
                 if(app.CanadianRegionDropDown.Value == 'QC')
                       QCrenewOpt =  [(1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','QCSOLARCap')*app.QC_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','QCWindCap')*app.QC_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,QCrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'Quebec Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end    
                 if(app.CanadianRegionDropDown.Value == 'ON')
                       ONrenewOpt =  [(1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','ONSOLARCap')*app.ON_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','ONWindCap')*app.ON_windkWhperkW(12)];
                      
                      app.UIAxes.cla;
                      bar(app.UIAxes,ONrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'Ontario Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end    
                 if(app.CanadianRegionDropDown.Value == 'NB')
                       NBrenewOpt =  [(1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','NBSOLARCap')*app.NB_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','NBWindCap')*app.NB_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,NBrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'New Brunswick Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end   
                 if(app.CanadianRegionDropDown.Value == 'PE')
                       PErenewOpt =  [(1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','PEISOLARCap')*app.PE_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','PEIWindCap')*app.PE_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,PErenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'PEI Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end   
                 if(app.CanadianRegionDropDown.Value == 'NS')
                       NSrenewOpt =  [(1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','NSSOLARCap')*app.NS_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','NSWindCap')*app.NS_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,NSrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'Nova Scotia Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end   
                 if(app.CanadianRegionDropDown.Value == 'BC')
                       BCrenewOpt =  [(1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','BCSOLARCap')*app.BC_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','BCWindCap')*app.BC_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,BCrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'British Columbia Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end    
                 if(app.CanadianRegionDropDown.Value == 'NL')
                       NLrenewOpt =  [(1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','NLSOLARCap')*app.NL_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','NLWindCap')*app.NL_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,NLrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'Newfoundland & Labrador Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end   
                 if(app.CanadianRegionDropDown.Value == 'YT')
                       YTrenewOpt =  [(1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','YTSOLARCap')*app.YT_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','YTWindCap')*app.YT_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,YTrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'Yukon Territory Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end    
                 if(app.CanadianRegionDropDown.Value == 'NT')
                       NTrenewOpt =  [(1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','NTSOLARCap')*app.NT_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','NTWindCap')*app.NT_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,NTrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'Northwest Territory Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end   
                 if(app.CanadianRegionDropDown.Value == 'NU')
                       NUrenewOpt =  [(1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(1), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(1)
                       (1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(2), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(2)
                       (1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(3), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(3)
                       (1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(4), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(4)
                       (1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(5), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(5)
                       (1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(6), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(6)
                       (1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(7), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(7)
                       (1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(8), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(8)
                       (1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(9), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(9)
                       (1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(10), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(10)
                       (1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(11), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(11)
                       (1/(30*24*1000))*evalin('base','NUSOLARCap')*app.NU_solarkWhperkW(12), (1/(30*24*1000))*evalin('base','NUWindCap')*app.NU_windkWhperkW(12)];
                   
                      app.UIAxes.cla;
                      bar(app.UIAxes,NUrenewOpt,0.5,'stacked');
                      ylim(app.UIAxes,[0 3000]);
                      ylabel(app.UIAxes,'Renewable Power Generated (average kW)');
                      title(app.UIAxes,'Nunavut Average Renewable Power Generated by Month')
                      legend(app.UIAxes,'Solar kW','Wind kW');
                      app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                      app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                      xlabel(app.UIAxes,'Month');
                      app.UIAxes.XGrid = 'off';
                 end


            case 'Renewable Energy Potential'
                
                if(app.CanadianRegionDropDown.Value == 'SK')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','SKRenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Saskatchewan Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'AB')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','ABRenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Alberta Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'MB')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','MBRenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Manitoba Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'QC')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','QCRenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Quebec Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'ON')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','ONRenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Ontario Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'NB')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','NBRenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'New Brunswick Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'PE')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','PERenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'PEI Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'NS')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','NSRenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Nova Scotia Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'BC')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','BCRenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'British Columbia Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'NL')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','NLRenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Newfoundland & Labrador Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 700]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'YT')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','YTRenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Yukon Territory Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'NT')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','NTRenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Northwest Territory Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                end
                if(app.CanadianRegionDropDown.Value == 'NU')
                  app.UIAxes.cla;
                  bar(app.UIAxes, evalin('base','NURenew'), 0.5, 'grouped'); 
                  legend(app.UIAxes,'Solar kWh/kW','Wind kWh/kW');
                  title(app.UIAxes, 'Nunavut Monthly Renewable Energy Production');
                  ylim(app.UIAxes,[0 400]);
                  ylabel(app.UIAxes, 'Energy Potential (kWh per kW of installed capacity)');
                  app.UIAxes.XTick = [1,2,3,4,5,6,7,8,9,10,11,12];
                  app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                  xlabel(app.UIAxes,'Month');
                  app.UIAxes.XGrid = 'off';
                  
                end
                
            case 'Cost'
                
                t = 0:1:30;
                if(app.CanadianRegionDropDown.Value =='SK')
                    app.UIAxes.cla;
                    SKGridOnly = app.SKgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,SKGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    SKGridWithDR = (4e6)*24*365/1000 - sum(app.SK_solarkWhperkW)*evalin('base','SKSOLARCap')/1000 -sum(app.SK_windkWhperkW)*evalin('base','SKWindCap')/1000;
  SKRenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','SKSOLARCap')/1000 + app.WindCapCost*evalin('base','SKWindCap')/1000 + (app.SolarOMCost*evalin('base','SKSOLARCap')/1000 + app.WindOMCost*evalin('base','SKWindCap')/1000 + app.SKgridCost*(SKGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,SKRenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');   
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='AB')
                    app.UIAxes.cla;
                    ABGridOnly = app.ABgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,ABGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    ABGridWithDR = (4e6)*24*365/1000 - sum(app.AB_solarkWhperkW)*evalin('base','ABSOLARCap')/1000 -sum(app.AB_windkWhperkW)*evalin('base','ABWindCap')/1000;
  ABRenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','ABSOLARCap')/1000 + app.WindCapCost*evalin('base','ABWindCap')/1000 + (app.SolarOMCost*evalin('base','ABSOLARCap')/1000 + app.WindOMCost*evalin('base','ABWindCap')/1000 + app.ABgridCost*(ABGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,ABRenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');   
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='MB')
                    app.UIAxes.cla;
                    MBGridOnly = app.MBgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,MBGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    MBGridWithDR = (4e6)*24*365/1000 - sum(app.MB_solarkWhperkW)*evalin('base','MBSOLARCap')/1000 -sum(app.MB_windkWhperkW)*evalin('base','MBWindCap')/1000;
  MBRenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','MBSOLARCap')/1000 + app.WindCapCost*evalin('base','MBWindCap')/1000 + (app.SolarOMCost*evalin('base','MBSOLARCap')/1000 + app.WindOMCost*evalin('base','MBWindCap')/1000 + app.MBgridCost*(MBGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,MBRenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');  
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='QC')
                    app.UIAxes.cla;
                    QCGridOnly = app.QCgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,QCGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    QCGridWithDR = (4e6)*24*365/1000 - sum(app.QC_solarkWhperkW)*evalin('base','QCSOLARCap')/1000 -sum(app.QC_windkWhperkW)*evalin('base','QCWindCap')/1000;
  QCRenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','QCSOLARCap')/1000 + app.WindCapCost*evalin('base','QCWindCap')/1000 + (app.SolarOMCost*evalin('base','QCSOLARCap')/1000 + app.WindOMCost*evalin('base','QCWindCap')/1000 + app.QCgridCost*(QCGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,QCRenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');     
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='ON')
                    app.UIAxes.cla;
                    ONGridOnly = app.ONgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,ONGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    ONGridWithDR = (4e6)*24*365/1000 - sum(app.ON_solarkWhperkW)*evalin('base','ONSOLARCap')/1000 -sum(app.ON_windkWhperkW)*evalin('base','ONWindCap')/1000;
  ONRenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','ONSOLARCap')/1000 + app.WindCapCost*evalin('base','ONWindCap')/1000 + (app.SolarOMCost*evalin('base','ONSOLARCap')/1000 + app.WindOMCost*evalin('base','ONWindCap')/1000 + app.ONgridCost*(ONGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,ONRenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs'); 
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='NB')
                    app.UIAxes.cla;
                    NBGridOnly = app.NBgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,NBGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    NBGridWithDR = (4e6)*24*365/1000 - sum(app.NB_solarkWhperkW)*evalin('base','NBSOLARCap')/1000 -sum(app.NB_windkWhperkW)*evalin('base','NBWindCap')/1000;
  NBRenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','NBSOLARCap')/1000 + app.WindCapCost*evalin('base','NBWindCap')/1000 + (app.SolarOMCost*evalin('base','NBSOLARCap')/1000 + app.WindOMCost*evalin('base','NBWindCap')/1000 + app.NBgridCost*(NBGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,NBRenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');     
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='PE')
                    app.UIAxes.cla;
                    PEGridOnly = app.PEgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,PEGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    PEGridWithDR = (4e6)*24*365/1000 - sum(app.PE_solarkWhperkW)*evalin('base','PEISOLARCap')/1000 -sum(app.PE_windkWhperkW)*evalin('base','PEIWindCap')/1000;
  PERenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','PEISOLARCap')/1000 + app.WindCapCost*evalin('base','PEIWindCap')/1000 + (app.SolarOMCost*evalin('base','PEISOLARCap')/1000 + app.WindOMCost*evalin('base','PEIWindCap')/1000 + app.PEgridCost*(PEGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,PERenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');    
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='NS')
                    app.UIAxes.cla;
                    NSGridOnly = app.NSgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,NSGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    NSGridWithDR = (4e6)*24*365/1000 - sum(app.NS_solarkWhperkW)*evalin('base','NSSOLARCap')/1000 -sum(app.NS_windkWhperkW)*evalin('base','NSWindCap')/1000;
  NSRenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','NSSOLARCap')/1000 + app.WindCapCost*evalin('base','NSWindCap')/1000 + (app.SolarOMCost*evalin('base','NSSOLARCap')/1000 + app.WindOMCost*evalin('base','NSWindCap')/1000 + app.NSgridCost*(NSGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,NSRenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');  
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='BC')
                    app.UIAxes.cla;
                    BCGridOnly = app.BCgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,BCGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    BCGridWithDR = (4e6)*24*365/1000 - sum(app.BC_solarkWhperkW)*evalin('base','BCSOLARCap')/1000 -sum(app.BC_windkWhperkW)*evalin('base','BCWindCap')/1000;
  BCRenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','BCSOLARCap')/1000 + app.WindCapCost*evalin('base','BCWindCap')/1000 + (app.SolarOMCost*evalin('base','BCSOLARCap')/1000 + app.WindOMCost*evalin('base','BCWindCap')/1000 + app.BCgridCost*(BCGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,BCRenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');    
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='NL')
                    app.UIAxes.cla;
                    NLGridOnly = app.NLgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,NLGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    NLGridWithDR = (4e6)*24*365/1000 - sum(app.NL_solarkWhperkW)*evalin('base','NLSOLARCap')/1000 -sum(app.NL_windkWhperkW)*evalin('base','NLWindCap')/1000;
  NLRenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','NLSOLARCap')/1000 + app.WindCapCost*evalin('base','NLWindCap')/1000 + (app.SolarOMCost*evalin('base','NLSOLARCap')/1000 + app.WindOMCost*evalin('base','NLWindCap')/1000 + app.NLgridCost*(NLGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,NLRenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');     
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='YT')
                    app.UIAxes.cla;
                    YTGridOnly = app.YTgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,YTGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    YTGridWithDR = (4e6)*24*365/1000 - sum(app.YT_solarkWhperkW)*evalin('base','YTSOLARCap')/1000 -sum(app.YT_windkWhperkW)*evalin('base','YTWindCap')/1000;
  YTRenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','YTSOLARCap')/1000 + app.WindCapCost*evalin('base','YTWindCap')/1000 + (app.SolarOMCost*evalin('base','YTSOLARCap')/1000 + app.WindOMCost*evalin('base','YTWindCap')/1000 + app.YTgridCost*(YTGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,YTRenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');  
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='NT')
                    app.UIAxes.cla;
                    NTGridOnly = app.NTgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,NTGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    NTGridWithDR = (4e6)*24*365/1000 - sum(app.NT_solarkWhperkW)*evalin('base','NTSOLARCap')/1000 -sum(app.NT_windkWhperkW)*evalin('base','NTWindCap')/1000;
  NTRenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','NTSOLARCap')/1000 + app.WindCapCost*evalin('base','NTWindCap')/1000 + (app.SolarOMCost*evalin('base','NTSOLARCap')/1000 + app.WindOMCost*evalin('base','NTWindCap')/1000 + app.NTgridCost*(NTGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,NTRenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');   
                    app.UIAxes.XGrid = 'on';
                end
                if(app.CanadianRegionDropDown.Value =='NU')
                    app.UIAxes.cla;
                    NUGridOnly = app.NUgridCost*(4e6)*24*365/1000;
                    plot(app.UIAxes,NUGridOnly*t);
                    app.UIAxes.XTick = [1,5,10,15,20,25,30];
                    app.UIAxes.XTickLabel = {'1','5','10','15','20','25','30'};
                    xlabel(app.UIAxes,'Years');
                    ylabel(app.UIAxes,'Cumulative Energy Cost (CAD)');
                    title(app.UIAxes,'Cumulative Energy Cost');
                    ylim(app.UIAxes, [0 330000000]);
                    NUGridWithDR = (4e6)*24*365/1000 - sum(app.NU_solarkWhperkW)*evalin('base','NUSOLARCap')/1000 -sum(app.NU_windkWhperkW)*evalin('base','NUWindCap')/1000;
  NURenewCost = app.DieselCapCost + app.SolarCapCost*evalin('base','NUSOLARCap')/1000 + app.WindCapCost*evalin('base','NUWindCap')/1000 + (app.SolarOMCost*evalin('base','NUSOLARCap')/1000 + app.WindOMCost*evalin('base','NUWindCap')/1000 + app.NUgridCost*(NUGridWithDR-91630))*t;
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,NURenewCost);
                    legend(app.UIAxes,'Grid Connection Only','Grid Connection & DERs');     
                    app.UIAxes.XGrid = 'on';
                end
                        
            case 'Simulated Voltage'
                app.UIAxes.cla;
                GenScope = evalin('base','GenScope');
                plot(app.UIAxes,GenScope.time, GenScope.signals(1).values);
                title(app.UIAxes, 'System Voltage');
                ylim(app.UIAxes,[2000 5000]);
                ylabel(app.UIAxes, 'Voltage (RMS)');
                app.UIAxes.XTick = [0,1*evalin('base','Yearsec'),2*evalin('base','Yearsec'),3*evalin('base','Yearsec'),4*evalin('base','Yearsec'),5*evalin('base','Yearsec'),6*evalin('base','Yearsec'),7*evalin('base','Yearsec'),8*evalin('base','Yearsec'),9*evalin('base','Yearsec'),10*evalin('base','Yearsec'),11*evalin('base','Yearsec')];
                app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                xlabel(app.UIAxes,'Month');
                legend(app.UIAxes, 'Voltage (RMS)')
                app.UIAxes.XGrid = 'on';
                
            case 'Simulated Frequency'
                app.UIAxes.cla;
                GenScope = evalin('base','GenScope');
                plot(app.UIAxes,GenScope.time, GenScope.signals(2).values);
                title(app.UIAxes, 'System Frequency');
                ylim(app.UIAxes,[58 62]);
                ylabel(app.UIAxes, 'Frequency (Hz)');
                app.UIAxes.XTick = [0,1*evalin('base','Yearsec'),2*evalin('base','Yearsec'),3*evalin('base','Yearsec'),4*evalin('base','Yearsec'),5*evalin('base','Yearsec'),6*evalin('base','Yearsec'),7*evalin('base','Yearsec'),8*evalin('base','Yearsec'),9*evalin('base','Yearsec'),10*evalin('base','Yearsec'),11*evalin('base','Yearsec')];
                app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                xlabel(app.UIAxes,'Month');
                legend(app.UIAxes, 'Frequency (Hz)')
                app.UIAxes.XGrid = 'on';
            
            case 'Simulated Power'
                app.UIAxes.cla;
                GenScope = evalin('base','GenScope');
                plot(app.UIAxes,GenScope.time, GenScope.signals(3).values);
                title(app.UIAxes, 'Generated Three-Phase Power');
                ylim(app.UIAxes,[-0.5 5e6]);
                ylabel(app.UIAxes, 'Power (W)');
                app.UIAxes.XTick = [0,1*evalin('base','Yearsec'),2*evalin('base','Yearsec'),3*evalin('base','Yearsec'),4*evalin('base','Yearsec'),5*evalin('base','Yearsec'),6*evalin('base','Yearsec'),7*evalin('base','Yearsec'),8*evalin('base','Yearsec'),9*evalin('base','Yearsec'),10*evalin('base','Yearsec'),11*evalin('base','Yearsec')];
                app.UIAxes.XTickLabel = {'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
                xlabel(app.UIAxes,'Month');
                app.UIAxes.XGrid = 'on';
                hold(app.UIAxes,"on");
                plot(app.UIAxes,GenScope.time, GenScope.signals(4).values);
                hold(app.UIAxes,"on");
                plot(app.UIAxes,GenScope.time, GenScope.signals(5).values);
                hold(app.UIAxes,"on");
                plot(app.UIAxes,GenScope.time, GenScope.signals(6).values);
                hold(app.UIAxes,"on");
                plot(app.UIAxes,GenScope.time, GenScope.signals(7).values);
                legend(app.UIAxes,'Solar','Wind','Diesel','Main Grid','Total');
                
                
        end
        end

        % Callback function
        function MonitorSimulationStatusButtonPushed(app, event)
       
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            set_param(app.modelNoSlash, 'SimulationCommand', 'stop');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DesignUIFigure = uifigure('Visible', 'off');
            app.DesignUIFigure.Name = 'Microgrid Design';
            app.DesignUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DesignUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'Optimization Inputs';
            app.CanadianRegionDropDownLabel = uilabel(app.Panel2_4);
            app.CanadianRegionDropDownLabel.Text = 'Canadian Region';
            app.CanadianRegionDropDown = uidropdown(app.Panel2_4);
            app.CanadianRegionDropDown.Items = {'AB', 'BC', 'MB', 'NB', 'NL', 'NS', 'NT', 'NU', 'ON', 'PE', 'QC', 'SK', 'YT'};
            app.CanadianRegionDropDown.ValueChangedFcn = createCallbackFcn(app, @CanadianRegionDropDownValueChanged, true);
            app.CanadianRegionDropDown.Value = 'AB';
            app.AveragePowerDemandMWEditFieldLabel = uilabel(app.Panel2_4);
            app.AveragePowerDemandMWEditFieldLabel.Text = 'Average Power Demand (MW)';
            app.AveragePowerDemandMWEditField = uieditfield(app.Panel2_4, 'text');
            app.AveragePowerDemandMWEditField.Editable = 'off';
            app.AveragePowerDemandMWEditField.Value = '4';
            app.OptimizeButton = uibutton(app.Panel2_4, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.MicrogridDistributedResourceOptimizationToolLabel = uilabel(app.LeftPanel);
            app.MicrogridDistributedResourceOptimizationToolLabel.Text = {'Microgrid Distributed Resource'; 'Optimization Tool'};
            app.Panel2_5 = uipanel(app.LeftPanel);
            app.Panel2_5.Title = 'Optimized Results';
            app.TotalWindTurbineCapacityMWEditFieldLabel = uilabel(app.Panel2_5);
            app.TotalWindTurbineCapacityMWEditFieldLabel.Text = 'Total Wind Turbine Capacity (MW)';
            app.TotalWindTurbineCapacityMWEditField = uieditfield(app.Panel2_5, 'numeric');
            app.TotalWindTurbineCapacityMWEditField.Editable = 'off';
            app.TotalDieselGeneratorCapacityMWEditFieldLabel = uilabel(app.Panel2_5);
            app.TotalDieselGeneratorCapacityMWEditFieldLabel.Text = 'Total Diesel Generator Capacity (MW) ';
            app.TotalDieselGeneratorCapacityMWEditField = uieditfield(app.Panel2_5, 'text');
            app.TotalDieselGeneratorCapacityMWEditField.Editable = 'off';
            app.TotalDieselGeneratorCapacityMWEditField.Value = '3';
            app.TotalPVCapacityMWEditFieldLabel = uilabel(app.Panel2_5);
            app.TotalPVCapacityMWEditFieldLabel.Text = 'Total PV Capacity (MW)';
            app.TotalPVCapacityMWEditField = uieditfield(app.Panel2_5, 'numeric');
            app.TotalPVCapacityMWEditField.Editable = 'off';
            app.InitialCostMillionCADEditFieldLabel = uilabel(app.Panel2_5);
            app.InitialCostMillionCADEditFieldLabel.Text = 'Initial Cost (Million CAD)';
            app.InitialCostMillionCADEditField = uieditfield(app.Panel2_5, 'numeric');
            app.InitialCostMillionCADEditField.Editable = 'off';
            app.Panel2_6 = uipanel(app.LeftPanel);
            app.Panel2_6.Title = 'Simulation Options';
            app.MonthofDisconnectionDropDownLabel = uilabel(app.Panel2_6);
            app.MonthofDisconnectionDropDownLabel.Text = 'Month of Disconnection';
            app.MonthofDisconnectionDropDown = uidropdown(app.Panel2_6);
            app.MonthofDisconnectionDropDown.Items = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
            app.MonthofDisconnectionDropDown.Value = 'Apr';
            app.SimulateButton = uibutton(app.Panel2_6, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TimeUntilReconnectiondaysEditFieldLabel = uilabel(app.Panel2_6);
            app.TimeUntilReconnectiondaysEditFieldLabel.Text = 'Time Until Reconnection (days)';
            app.TimeUntilReconnectiondaysEditField = uieditfield(app.Panel2_6, 'numeric');
            app.Label = uilabel(app.Panel2_6);
            app.Label.Text = {'For faster simulations, use an islanded time of 3 days or less. '; '(Decimal values are accepted)'};
            app.STOPButton = uibutton(app.Panel2_6, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Visible = 'off';
            app.STOPButton.Text = 'STOP';
            app.SimulatingPleaseWaitLabel = uilabel(app.Panel2_6);
            app.SimulatingPleaseWaitLabel.Visible = 'off';
            app.SimulatingPleaseWaitLabel.Text = 'Simulating... Please Wait';
            app.SimulationCompleteLabel = uilabel(app.Panel2_6);
            app.SimulationCompleteLabel.Visible = 'off';
            app.SimulationCompleteLabel.Text = 'Simulation Complete';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Month')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = {'Jan'; 'Feb'; 'Mar'; 'Apr'; 'May'; 'Jun'; 'Jul'; 'Aug'; 'Sep'; 'Oct'; 'Nov'; 'Dec'};
            app.UIAxes.YTick = [];
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Plotting Options';
            app.RenewableEnergyPotentialButton = uiradiobutton(app.ButtonGroup);
            app.RenewableEnergyPotentialButton.Text = 'Renewable Energy Potential';
            app.RenewableEnergyPotentialButton.Value = true;
            app.CostButton = uiradiobutton(app.ButtonGroup);
            app.CostButton.Visible = 'off';
            app.CostButton.Text = 'Cost';
            app.OptimizedPowerProductionButton = uiradiobutton(app.ButtonGroup);
            app.OptimizedPowerProductionButton.Visible = 'off';
            app.OptimizedPowerProductionButton.Text = 'Optimized Power Production';
            app.SimulatedVoltageButton = uiradiobutton(app.ButtonGroup);
            app.SimulatedVoltageButton.Visible = 'off';
            app.SimulatedVoltageButton.Text = 'Simulated Voltage';
            app.SimulatedFrequencyButton = uiradiobutton(app.ButtonGroup);
            app.SimulatedFrequencyButton.Visible = 'off';
            app.SimulatedFrequencyButton.Text = 'Simulated Frequency';
            app.SimulatedPowerButton = uiradiobutton(app.ButtonGroup);
            app.SimulatedPowerButton.Visible = 'off';
            app.SimulatedPowerButton.Text = 'Simulated Power';
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.Icon = 'OTU.JPG';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.DesignUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DesignUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DesignUIFigure)
        end
    end
end